classdef trackPartitionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        LabelListBox          matlab.ui.control.Label            % Track MD
        ListBox               matlab.ui.control.ListBox          % Load a M...
        LabelListBox2         matlab.ui.control.Label            % Mask MD
        ListBox2              matlab.ui.control.ListBox          % Load a M...
        Button                matlab.ui.control.Button           % Load Mov...
        Button2               matlab.ui.control.Button           % Load Mov...
        ListBox3              matlab.ui.control.ListBox          % Load a MD
        ListBox4              matlab.ui.control.ListBox          % Load a MD
        TabGroup              matlab.ui.container.TabGroup       % Load Fil...
        Tab                   matlab.ui.container.Tab            % Load Files
        Tab2                  matlab.ui.container.Tab            % Set Params
        Button3               matlab.ui.control.Button           % Add pair...
        LabelListBox5         matlab.ui.control.Label            % Jobs
        ListBox5              matlab.ui.control.ListBox          % Add a job
        Button4               matlab.ui.control.Button           % Remove f...
        LabelSpinner2         matlab.ui.control.Label            % Min. Mas...
        Spinner2              matlab.ui.control.Spinner          % [0 Inf]
        LabelSlider           matlab.ui.control.Label            % Min. Tra...
        Slider                matlab.ui.control.Slider           % [1 25]
        Spinner               matlab.ui.control.Spinner          % [1 25]
        LabelNumericEditField matlab.ui.control.Label            % Gaussian...
        NumericEditField      matlab.ui.control.NumericEditField % [0 1.000...
        Label                 matlab.ui.control.Label            % Upscale
        Slider2               matlab.ui.control.Slider           % [1 10]
        Spinner3              matlab.ui.control.Spinner          % [1 10]
        Label2                matlab.ui.control.Label            % Jobs
        ListBox6              matlab.ui.control.ListBox          % Add a job
        LabelSpinner4         matlab.ui.control.Label            % Analysis...
        Spinner4              matlab.ui.control.Spinner          % [1 199]
        Label3                matlab.ui.control.Label            % Analysis...
        Spinner5              matlab.ui.control.Spinner          % [2 500]
        LabelSwitch           matlab.ui.control.Label            % Perform ...
        Switch                matlab.ui.control.Switch           % No, Yes
        Button5               matlab.ui.control.Button           % Apply to...
        Tab3                  matlab.ui.container.Tab            % Run
        Button6               matlab.ui.control.Button           % RUN
        LabelGauge            matlab.ui.control.Label            % Jobs Done
        Gauge                 matlab.ui.control.Gauge            % [0 100]
        LabelKnob             matlab.ui.control.Label            % Turbo
        Knob                  matlab.ui.control.Knob             % [0 100]
        Tab4                  matlab.ui.container.Tab            % Help
        TextArea              matlab.ui.control.TextArea        
    end


    properties (Access = private)
        trackMLPath
        trackMLFile
        maskMLPath
        maskMLFile 
        trackMD
        maskMD
        trackChannel
        maskChannel
        jobList
        nJobs
    end

    properties (Access = public)

    end

    methods (Access = private)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create struct for job list
            app.jobList = struct('trackMD',[],'trackChannel',[],'maskMD',[],'maskChannel',[],'params',[]);
            app.nJobs = 0;
        
            app.ListBox.Items = {};
            app.ListBox2.Items = {};
            app.ListBox3.Items = {};
            app.ListBox4.Items = {};
            app.ListBox5.Items = {};
            
            app.TextArea.Value = fileread('trackPartitionGUIHelp');
        end

        % Button button pushed function
        function trackMLButtonPushed(app)
            [app.trackMLFile,app.trackMLPath] = uigetfile('*.mat','Select a MovieList',app.trackMLPath);
            % Load up all MovieData names inside this MovieList
            ML = load([app.trackMLPath,app.trackMLFile],'ML');
            ML = ML.ML;
            nMD = numel(ML.movieDataFile_);
            items = cell(1,nMD);
            itemsData = cell(1,nMD);
            for i = 1:nMD
                [~,name,~] = fileparts(ML.movieDataFile_{i});
                items{i} = name;
                itemsData{i} = ML.movieDataFile_{i};
            end            
            app.ListBox.Items = items;   
            app.ListBox.ItemsData = itemsData;  
        end

        % Button2 button pushed function
        function maskMLButtonPushed(app)
            [app.maskMLFile,app.maskMLPath] = uigetfile('*.mat','Select a MovieList',app.trackMLPath);
            % Load up all MovieData names inside this MovieList
            ML = load([app.maskMLPath,app.maskMLFile],'ML');
            ML = ML.ML;
            nMD = numel(ML.movieDataFile_);
            items = cell(1,nMD);
            itemsData = cell(1,nMD);
            for i = 1:nMD
                [~,name,~] = fileparts(ML.movieDataFile_{i});
                items{i} = name;
                itemsData{i} = ML.movieDataFile_{i};
            end            
            app.ListBox2.Items = items;   
            app.ListBox2.ItemsData = itemsData;
        end

        % ListBox value changed function
        function trackListBoxValueChanged(app)
            trackMDSelected = app.ListBox.Value;
            % load the selected MD and list available channels
            app.trackMD = load(trackMDSelected);
            app.trackMD = app.trackMD.MD;
            channels = app.trackMD.channels_;
            nChannels = numel(channels);
            items = cell(1,nChannels);
            for i = 1:nChannels
                items{i} = ['Channel ',num2str(i),': ',channels(i).excitationWavelength_];
            end
            app.ListBox3.Items = items;
            app.ListBox3.ItemsData = num2cell(1:nChannels);
            app.ListBox3.Value = 1;
        end

        % ListBox2 value changed function
        function maskListBoxValueChanged(app)
            maskMDSelected = app.ListBox.Value;
            % load the selected MD and list available channels
            app.maskMD = load(maskMDSelected);
            app.maskMD = app.maskMD.MD;
            channels = app.maskMD.channels_;
            nChannels = numel(channels);
            items = cell(1,nChannels);
            for i = 1:nChannels
                items{i} = ['Channel ',num2str(i),': ',channels(i).excitationWavelength_];
            end
            app.ListBox4.Items = items;
            app.ListBox4.ItemsData = num2cell(1:nChannels);
            app.ListBox4.Value = 1;
        end

        % ListBox3 value changed function
        function TrackChannelListBoxValueChanged(app)
            app.trackChannel = app.ListBox3.Value;
        end

        % ListBox4 value changed function
        function MaskChannelListBoxValueChanged(app)
            app.maskChannel = app.ListBox4.Value;
        end

        % Button3 button pushed function
        function addJobButtonPushed(app)
            % Check that the selected MD's have already been run with the needed Processes
            if isempty(app.trackMD.getProcessIndex('TrackingProcess'))
                uialert(app.UIFigure,'Tracking has not been run on the selected Track MD!','Oops!')
            elseif isempty(app.maskMD.getProcessIndex('SubResolutionProcess'))
                uialert(app.UIFigure,'Detection has not been run on the selected Mask MD!','Oops!')
            else
            
                % Add the selected track/mask MD pair to job list
                n = app.nJobs+1;
                app.jobList(n).trackMD = app.trackMD;
                app.jobList(n).trackChannel = app.trackChannel;
                app.jobList(n).maskMD = app.maskMD;
                app.jobList(n).maskChannel = app.maskChannel;
                
                % Check if partitioning has been run before. If so, get the parameters used.
                partitionIndex = app.trackMD.getProcessIndex('TrackPartitionProcess');
                if ~isempty(partitionIndex)
                    app.jobList(n).params = app.trackMD.processes_{partitionIndex}.funParams_;
                    neverRun = '';
                else
                    neverRun = '*';
                end
                
                % Add the pair to the displayed job list
                jobText = app.ListBox5.Items;
                [~,trackMDName,~] = fileparts(app.ListBox.Value);
                [~,maskMDName,~] = fileparts(app.ListBox2.Value);
                if n == 1;
                    jobText{n} = [neverRun,trackMDName,char(10),neverRun,maskMDName];
                    % Set analysis frame end default value 
                    app.Spinner5.Limits = [1,app.trackMD.nFrames_];
                else
                    jobText{n} = ['-------------',char(10),neverRun,trackMDName,...
                        char(10),neverRun,maskMDName];
                end
                app.ListBox5.Items = jobText;
                app.ListBox5.ItemsData = 1:n; % ListBox will return an index when item is selected
                
                % Duplicate the text on the ListBox on the second tab
                app.ListBox6.Items = jobText;
                app.ListBox6.ItemsData = 1:n;
                app.nJobs = n;
            end
        end

        % Button4 button pushed function
        function removeJobButtonPushed(app)
            remove = app.ListBox5.Value;
            
            app.jobList(remove) = [];
            app.ListBox5.Items(remove) = [];
            app.nJobs = (app.nJobs)-1;
            app.ListBox5.ItemsData = 1:app.nJobs;
            
            % Duplicate on the ListBox on the second tab
            app.ListBox6.Items(remove) = [];
            app.ListBox6.ItemsData = 1:app.nJobs;
        end

        % Slider value changed function
        function MinTrackLengthSliderValueChanged(app)
            value = app.Slider.Value;
            selectedJob = app.ListBox6.Value;
            % Discretize the value to an odd integer
            integer = round(value);
            if mod(integer,2) == 0
                if value > integer
                    value = integer+1;
                else
                    value = integer-1;
                end
            else
                value = integer;
            end
            app.Slider.Value = value;
            app.Spinner.Value = value;
            app.jobList(selectedJob).params.minTrackLength = value;
        end

        % Spinner value changed function
        function MinTrackLengthSpinnerValueChanged(app)
            value = app.Spinner.Value;
            selectedJob = app.ListBox6.Value;
             % Discretize the value to an odd integer
            integer = round(value);
            if mod(integer,2) == 0
                if value > integer
                    value = integer+1;
                else
                    value = integer-1;
                end
            else
                value = integer;
            end
            app.Slider.Value = value;
            app.Spinner.Value = value;
            app.jobList(selectedJob).params.minTrackLength = value;
        end

        % Spinner2 value changed function
        function MinMaskDiamSpinnerValueChanged(app)
            value = app.Spinner2.Value;
            selectedJob = app.ListBox6.Value;
            app.jobList(selectedJob).params.minMaskDiam = value;
        end

        % NumericEditField value changed function
        function GaussianThreshValueChanged(app)
            value = app.NumericEditField.Value;
            selectedJob = app.ListBox6.Value;
            app.jobList(selectedJob).params.gaussianThresh = value;
        end

        % ListBox6 value changed function
        function JobListBox2ValueChanged(app)
            selectedJob = app.ListBox6.Value;
            app.Slider.Value = app.jobList(selectedJob).params.minTrackLength;
            app.Spinner.Value = app.jobList(selectedJob).params.minTrackLength;
            app.Spinner2.Value = app.jobList(selectedJob).params.minMaskDiam;
            app.NumericEditField.Value = app.jobList(selectedJob).params.gaussianThresh;
            app.Slider2.Value = app.jobList(selectedJob).params.upscale;
            app.Spinner3.Value = app.jobList(selectedJob).params.upscale;
            app.Spinner4.Value = app.jobList(selectedJob).params.analysisStart;
            app.Spinner5.Value = app.jobList(selectedJob).params.analysisEnd;
            if app.jobList(selectedJob).params.runDiffAnalysis
                app.Switch.Value = 'Yes';
            else
                app.Switch.Value = 'No';
            end
            
        end

        % Slider2 value changed function
        function UpscaleSliderValueChanged(app)
            value = app.Slider2.Value;
            selectedJob = app.ListBox6.Value;
            % Discretize the value to an integer
            app.Slider2.Value = round(value);
            app.Spinner3.Value = round(value);
            app.jobList(selectedJob).params.upscale = value;
        end

        % Spinner3 value changed function
        function UpscaleSpinnerValueChanged(app)
            value = app.Spinner3.Value;
            selectedJob = app.ListBox6.Value;
            % Discretize the value to an integer
            app.Slider2.Value = round(value);
            app.Spinner3.Value = round(value);
            app.jobList(selectedJob).params.upscale = value;
        end

        % Spinner4 value changed function
        function StartFrameSpinnerValueChanged(app)
            value = app.Spinner4.Value;
            selectedJob = app.ListBox6.Value;
            app.jobList(selectedJob).params.analysisStart = round(value);
        end

        % Spinner5 value changed function
        function EndFrameSpinnerValueChanged(app)
            value = app.Spinner5.Value;
            selectedJob = app.ListBox6.Value;
            app.jobList(selectedJob).params.analysisEnd = round(value);
        end

        % Switch value changed function
        function DiffAnalSwitchValueChanged(app)
            value = app.Switch.Value;
            selectedJob = app.ListBox6.Value;
            if strcmp(value,'No')
                app.jobList(selectedJob).params.runDiffAnalysis = false;
            else
                app.jobList(selectedJob).params.runDiffAnalysis = true;
            end
        end

        % Button5 button pushed function
        function ApplyAllButtonPushed(app)
            % Apply current settings to all jobs
            for i = 1:app.nJobs
                app.jobList(i).params.minTrackLength = app.Slider.Value;
                app.jobList(i).params.minMaskDiam = app.Spinner2.Value;
                app.jobList(i).params.gaussianThresh = app.NumericEditField.Value;
                app.jobList(i).params.upscale = round(app.Spinner3.Value);
                app.jobList(i).params.analysisStart = round(app.Spinner4.Value);
                app.jobList(i).params.analysisEnd = round(app.Spinner5.Value);    
                if strcmp(app.Switch.Value,'No')
                    app.jobList(i).params.runDiffAnalysis = false;
                else
                    app.jobList(i).params.runDiffAnalysis = true;
                end
            end
        end

        % Button6 button pushed function
        function RunButtonPushed(app)
            % Set the gauge
            if app.nJobs > 20
                app.Gauge.MajorTicks = 1:5*round(app.nJobs/20):app.nJobs;
            else
                app.Gauge.MajorTicks = 1:app.nJobs;
            end
            % Run each job
            for i = 1:app.nJobs
                job = app.jobList(i);
                process = TrackPartitionProcess(job.trackMD,job.maskMD,...
                    job.trackChannel,job.maskChannel,job.params);
                process.run;
                job.trackMD.addProcess;
                job.trackMD.save;
                app.Gauge.Value = i;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Load Files';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @trackMLButtonPushed);
            app.Button.Text = 'Load MovieList';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @maskMLButtonPushed);
            app.Button2.Text = 'Load MovieList';
            app.ListBox3 = uilistbox(app.Tab);
            app.ListBox3.Items = {'Load a MD'};
            app.ListBox3.ValueChangedFcn = createCallbackFcn(app, @TrackChannelListBoxValueChanged);
            app.ListBox3.Value = 'Load a MD';
            app.ListBox4 = uilistbox(app.Tab);
            app.ListBox4.Items = {'Load a MD'};
            app.ListBox4.ValueChangedFcn = createCallbackFcn(app, @MaskChannelListBoxValueChanged);
            app.ListBox4.Value = 'Load a MD';
            app.LabelListBox = uilabel(app.Tab);
            app.LabelListBox.Text = 'Track MD';
            app.ListBox = uilistbox(app.Tab);
            app.ListBox.Items = {'Load a MovieList'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @trackListBoxValueChanged);
            app.ListBox.Value = 'Load a MovieList';
            app.LabelListBox2 = uilabel(app.Tab);
            app.LabelListBox2.Text = 'Mask MD';
            app.ListBox2 = uilistbox(app.Tab);
            app.ListBox2.Items = {'Load a MovieList'};
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @maskListBoxValueChanged);
            app.ListBox2.Value = 'Load a MovieList';
            app.Button3 = uibutton(app.Tab, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @addJobButtonPushed);
            app.Button3.Text = 'Add pair to job list->';
            app.LabelListBox5 = uilabel(app.Tab);
            app.LabelListBox5.Text = 'Jobs';
            app.ListBox5 = uilistbox(app.Tab);
            app.ListBox5.Items = {'Add a job'};
            app.ListBox5.Value = 'Add a job';
            app.Button4 = uibutton(app.Tab, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @removeJobButtonPushed);
            app.Button4.Text = 'Remove from job list <-';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Set Params';
            app.LabelSpinner2 = uilabel(app.Tab2);
            app.LabelSpinner2.Text = 'Min. Mask Diameter (nm)';
            app.Spinner2 = uispinner(app.Tab2);
            app.Spinner2.ValueChangedFcn = createCallbackFcn(app, @MinMaskDiamSpinnerValueChanged);
            app.Spinner2.Limits = [0 Inf];
            app.Spinner2.RoundFractionalValues = 'on';
            app.Spinner2.Value = 100;
            app.LabelSlider = uilabel(app.Tab2);
            app.LabelSlider.Text = 'Min. Track Length';
            app.Slider = uislider(app.Tab2);
            app.Slider.Limits = [1 25];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthSliderValueChanged);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 3;
            app.Spinner = uispinner(app.Tab2);
            app.Spinner.Step = 2;
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthSpinnerValueChanged);
            app.Spinner.Limits = [1 25];
            app.Spinner.ValueDisplayFormat = '%11.2g';
            app.Spinner.Value = 3;
            app.LabelNumericEditField = uilabel(app.Tab2);
            app.LabelNumericEditField.Text = 'Gaussian Threshold';
            app.NumericEditField = uieditfield(app.Tab2, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianThreshValueChanged);
            app.NumericEditField.Limits = [0 0.1];
            app.Label = uilabel(app.Tab2);
            app.Label.Text = 'Upscale';
            app.Slider2 = uislider(app.Tab2);
            app.Slider2.Limits = [1 10];
            app.Slider2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @UpscaleSliderValueChanged);
            app.Slider2.Value = 1;
            app.Spinner3 = uispinner(app.Tab2);
            app.Spinner3.ValueChangedFcn = createCallbackFcn(app, @UpscaleSpinnerValueChanged);
            app.Spinner3.Limits = [1 10];
            app.Spinner3.RoundFractionalValues = 'on';
            app.Spinner3.ValueDisplayFormat = '%11.2g';
            app.Spinner3.Value = 1;
            app.Label2 = uilabel(app.Tab2);
            app.Label2.Text = 'Jobs';
            app.ListBox6 = uilistbox(app.Tab2);
            app.ListBox6.Items = {'Add a job'};
            app.ListBox6.ValueChangedFcn = createCallbackFcn(app, @JobListBox2ValueChanged);
            app.ListBox6.Value = 'Add a job';
            app.LabelSpinner4 = uilabel(app.Tab2);
            app.LabelSpinner4.Text = 'Analysis Start Frame';
            app.Spinner4 = uispinner(app.Tab2);
            app.Spinner4.ValueChangedFcn = createCallbackFcn(app, @StartFrameSpinnerValueChanged);
            app.Spinner4.Limits = [1 199];
            app.Spinner4.RoundFractionalValues = 'on';
            app.Spinner4.Value = 1;
            app.Label3 = uilabel(app.Tab2);
            app.Label3.Text = 'Analysis End Frame';
            app.Spinner5 = uispinner(app.Tab2);
            app.Spinner5.ValueChangedFcn = createCallbackFcn(app, @EndFrameSpinnerValueChanged);
            app.Spinner5.Limits = [2 500];
            app.Spinner5.RoundFractionalValues = 'on';
            app.Spinner5.Value = 200;
            app.LabelSwitch = uilabel(app.Tab2);
            app.LabelSwitch.Text = 'Perform Diffusion Analysis';
            app.Switch = uiswitch(app.Tab2, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @DiffAnalSwitchValueChanged);
            app.Switch.Value = 'Yes';
            app.Button5 = uibutton(app.Tab2, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @ApplyAllButtonPushed);
            app.Button5.Text = 'Apply to all';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Run';
            app.Button6 = uibutton(app.Tab3, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed);
            app.Button6.Text = 'RUN';
            app.LabelGauge = uilabel(app.Tab3);
            app.LabelGauge.Text = 'Jobs Done';
            app.Gauge = uigauge(app.Tab3, 'circular');
            app.Gauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.LabelKnob = uilabel(app.Tab3);
            app.LabelKnob.Text = 'Turbo';
            app.Knob = uiknob(app.Tab3, 'continuous');
            app.Knob.MajorTicks = [0 25 50 75 100];
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Help';
            app.TextArea = uitextarea(app.Tab4);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trackPartitionGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end